classdef app_normal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Normal                     matlab.ui.Figure
        UITable                    matlab.ui.control.Table
        RecordedTextTextAreaLabel  matlab.ui.control.Label
        RecordedTextTextArea       matlab.ui.control.TextArea
        VirtualKeyboardLabel       matlab.ui.control.Label
        LeftButton                 matlab.ui.control.Button
        RightButton                matlab.ui.control.Button
        DownButton                 matlab.ui.control.Button
        UpButton                   matlab.ui.control.Button
        SelectButton               matlab.ui.control.Button
        UpLeftButton               matlab.ui.control.Button
        DownLeftButton             matlab.ui.control.Button
        DownRightButton            matlab.ui.control.Button
        UpRightButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        row = 5;
        col =5; % Description
        str = '';
        dat = {'E','A','R','I','.','Back','Space','Enter';
                                'O','T','N','S','B','F','W','Y';
                                'L','C','U','D','K','V','X','Z';
                                'P','M','H','G','J','Q','1','2';
                                '3','4','5','6','7','8','9','0';
                                '+','x','/','-','=','&','?','!'};
        sig_blink = zeros(300,3);
        sig_up = zeros(400,3);
        sig_dn = zeros(400,3);
        sig_rt = zeros(400,3);
        sig_lt = zeros(400,3);
        sig_tr = zeros(400,3);
        sig_tl = zeros(400,3);
        sig_br = zeros(400,3);
        sig_bl = zeros(400,3);

    end
    
    methods (Access = private)
        
        function func(app)
            app.UITable.Data = app.dat;
            app.UITable.RowStriping = 'off';
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.UITable,s);
        end
        
        function highlight(app,row,col)
            s = uistyle('BackgroundColor','#0072BD');
            addStyle(app.UITable,s,'cell',[row,col]);
        end
        function dehighlight(app,row,col)
            s = uistyle('BackgroundColor','white');
            addStyle(app.UITable,s,'cell',[row,col]);
        end
        
        function generate_signals(app)
            bl = csvread('BottomLeft.csv',2,0);
            br = csvread('BottomRight.csv',2,0);
            tl = csvread('TopLeft.csv',2,0);
            tr = csvread('TopRight.csv',2,0);
            bnk = csvread('F0000CH2.CSV',0,3,[0,3,2499,4]);
            
            %%Blink
            app.sig_blink(1:300,1) = tr(1:300,1);
            app.sig_blink(1:300,2) = bnk(851:1150,2)*22;
            app.sig_blink(1:300,3) = bnk(851:1150,2)*22;         
            %%Up
            app.sig_up(1:400,1) = tr(1:400,1);
            app.sig_up(:,2) = tr(951:1350,2) - 2;
            app.sig_up(:,3) = 0;            
            %%Down         
            app.sig_dn(1:400,1) = bl(1:400,1);
            app.sig_dn(:,2) = bl(1101:1500,2) - 2;
            app.sig_dn(:,3) = 0;            
            %%Right          
            app.sig_rt(1:400,1) = tr(1:400,1);
            app.sig_rt(:,2) = 0;
            app.sig_rt(:,3) = tr(951:1350,3) - 3;            
            %%Left           
            app.sig_lt(1:400,1) = bl(1:400,1);
            app.sig_lt(:,2) = 0;
            app.sig_lt(:,3) = bl(1101:1500,3) - 3;          
            %%Top Right
            app.sig_tr(1:400,1) = tr(1:400,1);
            app.sig_tr(:,2) = tr(951:1350,2) - 2;
            app.sig_tr(:,3) = tr(951:1350,3) - 3;
            %%Top Left
            app.sig_tl(1:400,1) = tl(1:400,1);
            app.sig_tl(:,2) = tl(576:975,2) - 2;
            app.sig_tl(:,3) = tl(576:975,3) - 3;
            %%Bottom Right
            app.sig_br(1:400,1) = br(1:400,1);
            app.sig_br(:,2) = br(576:975,2) - 2;
            app.sig_br(:,3) = br(576:975,3) - 3;
            %%Bottom Left
            app.sig_bl(1:400,1) = bl(1:400,1);
            app.sig_bl(:,2) = bl(1101:1500,2) - 2;
            app.sig_bl(:,3) = bl(1101:1500,3) - 3;
        end


        function ret = algo(app,input,row,col)
            
            interval = 1.5;
            blink_int = 0.2;
            threshold = 1;
            sig = input;
            %plot(sig(:,1),[sig(:,2) sig(:,3)]);
            ret = [row,col];
            skip_samples = 40;
            mem_1 = 0;
            mem_2 = 0;
            pos1 = 0;
            neg1 = 0;
            pos2 = 0;
            neg2 = 0;
            blink1 = 0;
            blink2 = 0;
            ch1 = -1;
            ch2 = -30;
            count = 0;
            [len, ~] = size(sig);
            for i = 1:len
                if sig(i,2) > threshold && i > mem_1+skip_samples
                    j = i;
                    while sig(j,1)-sig(i,1)<interval 
                        if j > len-1
                            break
                        end
                        if sig(j,2) < -1*threshold
                            if sig(j,1)-sig(i,1) < blink_int
                                blink1 = 1;
                            else
                                ch1 = i;
                                pos1 = 1;
                            end
                            mem_1 = j;
                            break
                        end
                        j=j+1;
                    end
                elseif sig(i,2) < -1*threshold && i > mem_1+skip_samples
                    j = i;
                    while sig(j,1)-sig(i,1)<interval
                        if j > len-1
                            break
                        end
                        if sig(j,2) > threshold
                            ch1 = i;
                            neg1 = 1;
                            mem_1 = j;
                            break
                        end
                        j=j+1;
                    end
                end
                if sig(i,3) > threshold && i > mem_2+skip_samples
                    j1 = i;
                    while sig(j1,1)-sig(i,1)<interval 
                        if j1 > len-1
                            break
                        end
                        if sig(j1,3) < -1*threshold
                            if sig(j1,1)-sig(i,1) < blink_int
                                blink2 = 1;
                            else
                                ch2 = i;
                                pos2 = 1;
                            end
                            mem_2 = j1;
                            break
                        end
                        j1=j1+1;
                    end
                elseif sig(i,3) < -1*threshold && i > mem_2+skip_samples
                    j1 = i;
                    while sig(j1,1)-sig(i,1)<interval
                        if j1 > len-1
                            break
                        end
                        if sig(j1,3) > threshold
                            ch2 = i;
                            neg2 = 1;
                            mem_2 = j1;
                            break
                        end
                        j1=j1+1;
                    end
                end
                if pos1 == 1 || pos2 == 1 || neg1 == 1 || neg2 == 1 || blink1 == 1 || blink2 == 1
                    count = count + 1;
                end
               
                if count > 12
                    if abs(ch1-ch2) < 10
                        if pos1 == 1 && pos2 == 1 && neg1 == 0 && neg2 == 0
                            dehighlight(app,row,col)
                            highlight(app,row-1,col+1)
                            ret = [row-1,col+1];
                        elseif pos1 == 1 && pos2 == 0 && neg1 == 0 && neg2 == 1
                            dehighlight(app,row,col)
                            highlight(app,row-1,col-1)
                            ret = [row-1,col-1];
                        elseif pos1 == 0 && pos2 == 0 && neg1 == 1 && neg2 == 1
                            dehighlight(app,row,col)
                            highlight(app,row+1,col-1)
                            ret = [row+1,col-1];
                        elseif pos1 == 0 && pos2 == 1 && neg1 == 1 && neg2 == 0
                            dehighlight(app,row,col)
                            highlight(app,row+1,col+1)
                            ret = [row+1,col+1];
                        end
                    else
                        if pos1 == 1 && pos2 == 0 && neg1 == 0 && neg2 == 0
                            dehighlight(app,row,col)
                            highlight(app,row-1,col)
                            ret = [row-1,col];
                        elseif pos1 == 0 && pos2 == 0 && neg1 == 1 && neg2 == 0
                            dehighlight(app,row,col)
                            highlight(app,row+1,col)
                            ret = [row+1,col];
                        elseif pos1 == 0 && pos2 == 1 && neg1 == 0 && neg2 == 0
                            dehighlight(app,row,col)
                            highlight(app,row,col+1)
                            ret = [row,col+1];
                        elseif pos1 == 0 && pos2 == 0 && neg1 == 0 && neg2 == 1
                            dehighlight(app,row,col)
                            highlight(app,row,col-1)
                            ret = [row,col-1];
                        elseif pos1 == 0 && pos2 == 0 && neg1 == 0 && neg2 == 0 && blink1 ==1 && blink2==1
                            highlight(app,row,col)
                            ret = [row,col];
                        end
                    end
                pos1 = 0;
                neg1 = 0;
                pos2 = 0;
                neg2 = 0;
                count = 0;
                blink1 = 0;
                blink2 = 0;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            func(app)
            generate_signals(app)
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            rc = algo(app,app.sig_blink,app.row,app.col);
            temp = app.dat(app.row,app.col);
            disp(temp)
            if strcmp(temp{1},'Space') == 1
                app.str = strcat(app.str,{' '});
            elseif strcmp(temp{1},'Back') == 1
                app.str = app.str(1:end-1);
            elseif strcmp(temp{1},'Enter') == 1
                app.str = strcat(app.str,{'\n'});
            else
                app.str = strcat(app.str,temp{1});
            end
            
            app.row = rc(1);
            app.col = rc(2);
            app.RecordedTextTextArea.Value = app.str;
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            rc = algo(app,app.sig_up,app.row,app.col);
            app.row = rc(1);
            app.col = rc(2);
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            rc = algo(app,app.sig_dn,app.row,app.col);
            app.row = rc(1);
            app.col = rc(2);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            rc = algo(app,app.sig_rt,app.row,app.col);
            app.row = rc(1);
            app.col = rc(2);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            rc = algo(app,app.sig_lt,app.row,app.col);
            app.row = rc(1);
            app.col = rc(2);
        end

        % Button pushed function: UpRightButton
        function UpRightButtonPushed(app, event)
            rc = algo(app,app.sig_tr,app.row,app.col);
            app.row = rc(1);
            app.col = rc(2);
        end

        % Button pushed function: UpLeftButton
        function UpLeftButtonPushed(app, event)
            rc = algo(app,app.sig_tl,app.row,app.col);
            app.row = rc(1);
            app.col = rc(2);
        end

        % Button pushed function: DownLeftButton
        function DownLeftButtonPushed(app, event)
            rc = algo(app,app.sig_bl,app.row,app.col);
            app.row = rc(1);
            app.col = rc(2);
        end

        % Button pushed function: DownRightButton
        function DownRightButtonPushed(app, event)
            rc = algo(app,app.sig_br,app.row,app.col);
            app.row = rc(1);
            app.col = rc(2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Normal = uifigure('Visible', 'off');
            app.Normal.Name = 'UI Figure';
            app.UITable = uitable(app.Normal);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.UITable.RowName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.RecordedTextTextAreaLabel = uilabel(app.Normal);
            app.RecordedTextTextAreaLabel.Text = 'Recorded Text';
            app.RecordedTextTextArea = uitextarea(app.Normal);
            app.VirtualKeyboardLabel = uilabel(app.Normal);
            app.VirtualKeyboardLabel.Text = 'Virtual Keyboard';
            app.LeftButton = uibutton(app.Normal, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.Normal, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.DownButton = uibutton(app.Normal, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.UpButton = uibutton(app.Normal, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.SelectButton = uibutton(app.Normal, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.UpLeftButton = uibutton(app.Normal, 'push');
            app.UpLeftButton.ButtonPushedFcn = createCallbackFcn(app, @UpLeftButtonPushed, true);
            app.UpLeftButton.Text = 'Up-Left';
            app.DownLeftButton = uibutton(app.Normal, 'push');
            app.DownLeftButton.ButtonPushedFcn = createCallbackFcn(app, @DownLeftButtonPushed, true);
            app.DownLeftButton.Text = 'Down-Left';
            app.DownRightButton = uibutton(app.Normal, 'push');
            app.DownRightButton.ButtonPushedFcn = createCallbackFcn(app, @DownRightButtonPushed, true);
            app.DownRightButton.Text = 'Down-Right';
            app.UpRightButton = uibutton(app.Normal, 'push');
            app.UpRightButton.ButtonPushedFcn = createCallbackFcn(app, @UpRightButtonPushed, true);
            app.UpRightButton.Text = 'Up-Right';
            % Show the figure after all components are created
            app.Normal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_normal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Normal)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Normal)
        end
    end
end