classdef eog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InputKeyBoard              matlab.ui.Figure
        UITable                    matlab.ui.control.Table
        RecordedTextTextAreaLabel  matlab.ui.control.Label
        RecordedTextTextArea       matlab.ui.control.TextArea
        VirtualKeyboardLabel       matlab.ui.control.Label
        Button_4                   matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        Button_3                   matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        Button_8                   matlab.ui.control.Button
        Button_7                   matlab.ui.control.Button
        Button_6                   matlab.ui.control.Button
        Button_5                   matlab.ui.control.Button
        InputTextEditFieldLabel    matlab.ui.control.Label
        InputTextEditField         matlab.ui.control.EditField
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        row = 5;
        col =5; % Description
        str = '';
        dat = {'E','A','R','I','.','Back','Space','Enter';
                                'O','T','N','S','B','F','W','Y';
                                'L','C','U','D','K','V','X','Z';
                                'P','M','H','G','J','Q','1','2';
                                '3','4','5','6','7','8','9','0';
                                '+','x','/','-','=','&','?','!'};
        sig_blink = zeros(300,3);
        sig_up = zeros(400,3);
        sig_dn = zeros(400,3);
        sig_rt = zeros(400,3);
        sig_lt = zeros(400,3);
        sig_tr = zeros(400,3);
        sig_tl = zeros(400,3);
        sig_br = zeros(400,3);
        sig_bl = zeros(400,3);
        counter = 1;

    end
    
    methods (Access = private)
        
        function func(app)
            app.UITable.Data = app.dat;
            app.UITable.RowStriping = 'off';
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.UITable,s);
        end
        
        function highlight(app,row,col)
            s = uistyle('BackgroundColor','#0072BD');
            if row == 0 && col ~= 0
                addStyle(app.UITable,s,'column',col);
            elseif row ~= 0 && col ~= 0
                addStyle(app.UITable,s,'cell',[row,col]);
            end
        end
        function dehighlight(app,row,col)
            s = uistyle('BackgroundColor','white');
            if row == 0 && col ~= 0
                addStyle(app.UITable,s,'column',col);
            elseif row ~= 0 && col ~= 0
                addStyle(app.UITable,s,'cell',[row,col]);
            end
        end
        
        function generate_signals(app)
            bl = csvread('BottomLeft.csv',2,0);
            br = csvread('BottomRight.csv',2,0);
            tl = csvread('TopLeft.csv',2,0);
            tr = csvread('TopRight.csv',2,0);
            bnk = csvread('F0000CH2.CSV',0,3,[0,3,2499,4]);
            a = -0.1;
            b = 0.1;
            r = (b-a).*rand(1,400) + a;
            %%Blink
            app.sig_blink(1:300,1) = tr(1:300,1);
            app.sig_blink(1:300,2) = bnk(851:1150,2)*22;
            app.sig_blink(1:300,3) = bnk(851:1150,2)*22;         
            %%Up
            app.sig_up(1:400,1) = tr(1:400,1);
            app.sig_up(:,2) = tr(951:1350,2) - 2;
            app.sig_up(:,3) = r;            
            %%Down         
            app.sig_dn(1:400,1) = bl(1:400,1);
            app.sig_dn(:,2) = bl(1101:1500,2) - 2;
            app.sig_dn(:,3) = r;            
            %%Right          
            app.sig_rt(1:400,1) = tr(1:400,1);
            app.sig_rt(:,2) = r;
            app.sig_rt(:,3) = tr(951:1350,3) - 3;            
            %%Left           
            app.sig_lt(1:400,1) = bl(1:400,1);
            app.sig_lt(:,2) = r;
            app.sig_lt(:,3) = bl(1101:1500,3) - 3;          
            %%Top Right
            app.sig_tr(1:400,1) = tr(1:400,1);
            app.sig_tr(:,2) = tr(951:1350,2) - 2;
            app.sig_tr(:,3) = tr(951:1350,3) - 3;
            %%Top Left
            app.sig_tl(1:400,1) = tl(1:400,1);
            app.sig_tl(:,2) = tl(576:975,2) - 2;
            app.sig_tl(:,3) = tl(576:975,3) - 3;
            %%Bottom Right
            app.sig_br(1:400,1) = br(1:400,1);
            app.sig_br(:,2) = br(576:975,2) - 2;
            app.sig_br(:,3) = br(576:975,3) - 3;
            %%Bottom Left
            app.sig_bl(1:400,1) = bl(1:400,1);
            app.sig_bl(:,2) = bl(1101:1500,2) - 2;
            app.sig_bl(:,3) = bl(1101:1500,3) - 3;
        end


        function ret = algo(app,input,row,col)
            
            interval = 1.5;
            blink_int = 0.2;
            threshold = 1;
            sig = input;
            %plot(sig(:,1),[sig(:,2) sig(:,3)]);
            ret = [row,col];
            skip_samples = 40;
            mem_1 = 0;
            mem_2 = 0;
            pos1 = 0;
            neg1 = 0;
            pos2 = 0;
            neg2 = 0;
            blink1 = 0;
            blink2 = 0;
            ch1 = -1;
            ch2 = -30;
            count = 0;
            [len, ~] = size(sig);
            for i = 1:len
                if sig(i,2) > threshold && i > mem_1+skip_samples
                    j = i;
                    while sig(j,1)-sig(i,1)<interval 
                        if j > len-1
                            break
                        end
                        if sig(j,2) < -1*threshold
                            if sig(j,1)-sig(i,1) < blink_int
                                blink1 = 1;
                            else
                                ch1 = i;
                                pos1 = 1;
                            end
                            mem_1 = j;
                            break
                        end
                        j=j+1;
                    end
                elseif sig(i,2) < -1*threshold && i > mem_1+skip_samples
                    j = i;
                    while sig(j,1)-sig(i,1)<interval
                        if j > len-1
                            break
                        end
                        if sig(j,2) > threshold
                            ch1 = i;
                            neg1 = 1;
                            mem_1 = j;
                            break
                        end
                        j=j+1;
                    end
                end
                if sig(i,3) > threshold && i > mem_2+skip_samples
                    j1 = i;
                    while sig(j1,1)-sig(i,1)<interval 
                        if j1 > len-1
                            break
                        end
                        if sig(j1,3) < -1*threshold
                            if sig(j1,1)-sig(i,1) < blink_int
                                blink2 = 1;
                            else
                                ch2 = i;
                                pos2 = 1;
                            end
                            mem_2 = j1;
                            break
                        end
                        j1=j1+1;
                    end
                elseif sig(i,3) < -1*threshold && i > mem_2+skip_samples
                    j1 = i;
                    while sig(j1,1)-sig(i,1)<interval
                        if j1 > len-1
                            break
                        end
                        if sig(j1,3) > threshold
                            ch2 = i;
                            neg2 = 1;
                            mem_2 = j1;
                            break
                        end
                        j1=j1+1;
                    end
                end
                if pos1 == 1 || pos2 == 1 || neg1 == 1 || neg2 == 1 || blink1 == 1 || blink2 == 1
                    count = count + 1;
                end
                if count > 12
                    if abs(ch1-ch2) < 10
                        if pos1 == 1 && pos2 == 1 && neg1 == 0 && neg2 == 0
                            app.Button_5.BackgroundColor = 'red';
                            pause(1.5);
                            select(app,5);
                            app.Button_5.BackgroundColor = 'white';
                        elseif pos1 == 1 && pos2 == 0 && neg1 == 0 && neg2 == 1
                            app.Button_8.BackgroundColor = 'red';
                            pause(1.5);
                            select(app,8);
                            app.Button_8.BackgroundColor = 'white';
                        elseif pos1 == 0 && pos2 == 0 && neg1 == 1 && neg2 == 1
                            app.Button_7.BackgroundColor = 'red';
                            pause(1.5);
                            select(app,7);
                            app.Button_7.BackgroundColor = 'white';
                        elseif pos1 == 0 && pos2 == 1 && neg1 == 1 && neg2 == 0
                            app.Button_6.BackgroundColor = 'red';
                            pause(1.5);
                            select(app,6);
                            app.Button_6.BackgroundColor = 'white';
                        end
                    else
                        if pos1 == 1 && pos2 == 0 && neg1 == 0 && neg2 == 0
                            app.Button.BackgroundColor = 'red';
                            pause(1.5);
                            select(app,1);
                            app.Button.BackgroundColor = 'white';
                        elseif pos1 == 0 && pos2 == 0 && neg1 == 1 && neg2 == 0
                            app.Button_3.BackgroundColor = 'red';
                            pause(1.5);
                            select(app,3);
                            app.Button_3.BackgroundColor = 'white';
                        elseif pos1 == 0 && pos2 == 1 && neg1 == 0 && neg2 == 0
                            app.Button_2.BackgroundColor = 'red';
                            pause(1.5);
                            select(app,2);
                            app.Button_2.BackgroundColor = 'white';
                        elseif pos1 == 0 && pos2 == 0 && neg1 == 0 && neg2 == 1
                            app.Button_4.BackgroundColor = 'red';
                            pause(1.5);
                            select(app,4);
                            app.Button_4.BackgroundColor = 'white';
                        elseif pos1 == 0 && pos2 == 0 && neg1 == 0 && neg2 == 0 && blink1 ==1 && blink2==1
                            highlight(app,row,col)
                            ret = [row,col];
                        end
                    end
                pos1 = 0;
                neg1 = 0;
                pos2 = 0;
                neg2 = 0;
                count = 0;
                blink1 = 0;
                blink2 = 0;
                end
            end
        end
        
        function select(app,axis)
            if mod(app.counter,2) == 1
               dehighlight(app,app.row,app.col)
               highlight(app,0,axis)
               pause(1);
               app.col = axis;
               app.counter = app.counter + 1;
            elseif mod(app.counter,2) == 0
               dehighlight(app,0,app.col)
               highlight(app,axis,app.col)
               pause(1);
               app.row = axis;
               app.counter = app.counter + 1;
               temp = app.dat(app.row,app.col);
                if strcmp(temp{1},'Space') == 1
                    app.str = strcat(app.str,{' '});
                elseif strcmp(temp{1},'Back') == 1
                    app.str = app.str(1:end-1);
                elseif strcmp(temp{1},'Enter') == 1
                    app.str = strcat(app.str,{'\n'});
                else
                    app.str = strcat(app.str,temp{1});
                end
                app.RecordedTextTextArea.Value = app.str;
            end
        end
        
        function results = word_sig_gen(app,word)
            main_sig = zeros(strlength(word)*400*2,3);
            x = 0:5e-3:strlength(word)*400*2*5e-3;
            x = x(1:end-1)';
            main_sig(1:strlength(word)*400*2,1) = x;
            k = 0;
            for i = 1:strlength(word)
                tmp = word(i);
                if tmp == ' '
                    tmp = 'Space';
                end
                letter = cellfun(@(x)isequal(x,tmp),app.dat);
                [row_dat,col_dat] = find(letter);
                mov = identify(app,col_dat);
                main_sig(k*400+1:(k+1)*400,2:3)=mov(1:400,2:3);
                k = k + 1;
                mov = identify(app,row_dat);
                main_sig(k*400+1:(k+1)*400,2:3)=mov(1:400,2:3);
                k = k + 1;
            end
            results = main_sig;
        end
        
        function ret = identify(app,n)
            if n == 1
                ret = app.sig_up;
            elseif n == 2
                ret = app.sig_rt;
            elseif n == 3
                ret = app.sig_dn;
            elseif n == 4
                ret = app.sig_lt;
            elseif n == 5
                ret = app.sig_tr;
            elseif n == 6
                ret = app.sig_br;
            elseif n == 7
                ret = app.sig_bl;
            elseif n == 8
                ret = app.sig_tl;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            func(app)
            generate_signals(app)
            
        end

        % Value changed function: InputTextEditField
        function InputTextEditFieldValueChanged(app, event)
            app.RecordedTextTextArea.Value = '';
            app.str = '';
            value = app.InputTextEditField.Value;
            wave = word_sig_gen(app,value);
            ax1 = app.UIAxes ;
            ax2 = app.UIAxes2 ;
            plot(ax1,wave(:,1),wave(:,2));
            %ax1.Title = 'Channel-1';
            plot(ax2,wave(:,1),wave(:,3));
            %ax1.Title = 'Channel-2';
            algo(app,wave,app.row,app.col);
            dehighlight(app,app.row,app.col);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InputKeyBoard = uifigure('Visible', 'off');
            app.InputKeyBoard.Name = 'UI Figure';
            app.UITable = uitable(app.InputKeyBoard);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.UITable.RowName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.RecordedTextTextAreaLabel = uilabel(app.InputKeyBoard);
            app.RecordedTextTextAreaLabel.Text = 'Recorded Text';
            app.RecordedTextTextArea = uitextarea(app.InputKeyBoard);
            app.VirtualKeyboardLabel = uilabel(app.InputKeyBoard);
            app.VirtualKeyboardLabel.Text = 'Virtual Keyboard';
            app.Button_4 = uibutton(app.InputKeyBoard, 'push');
            app.Button_4.Text = '4';
            app.Button_2 = uibutton(app.InputKeyBoard, 'push');
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.InputKeyBoard, 'push');
            app.Button_3.Text = '3';
            app.Button = uibutton(app.InputKeyBoard, 'push');
            app.Button.Text = '1';
            app.Button_8 = uibutton(app.InputKeyBoard, 'push');
            app.Button_8.Text = '8';
            app.Button_7 = uibutton(app.InputKeyBoard, 'push');
            app.Button_7.Text = '7';
            app.Button_6 = uibutton(app.InputKeyBoard, 'push');
            app.Button_6.Text = '6';
            app.Button_5 = uibutton(app.InputKeyBoard, 'push');
            app.Button_5.Text = '5';
            app.InputTextEditFieldLabel = uilabel(app.InputKeyBoard);
            app.InputTextEditFieldLabel.Text = 'Input Text';
            app.InputTextEditField = uieditfield(app.InputKeyBoard, 'text');
            app.InputTextEditField.ValueChangedFcn = createCallbackFcn(app, @InputTextEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.InputKeyBoard);
            title(app.UIAxes, 'Channel-1')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.InputKeyBoard);
            title(app.UIAxes2, 'Channel-2')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.InputKeyBoard.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eog

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InputKeyBoard)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InputKeyBoard)
        end
    end
end